classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LabelResultado       matlab.ui.control.Label
        PrototipoComputacionalparaPredecirPatologasOvricasLabel  matlab.ui.control.Label
        LimpiarButton        matlab.ui.control.Button
        DiagnosticarButton   matlab.ui.control.Button
        DibujarlesionButton  matlab.ui.control.Button
        CargarImagenButton   matlab.ui.control.Button
        UIAxesMascara        matlab.ui.control.UIAxes
        UIAxesImagen         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img              % Imagen original
        mask             % Máscara dibujada
        subimg           % ROI
        mask_crop        % Máscara recortada
        modelo           % Modelo KNN
        mu               % Media de normalización
        sigma            % Desviación estándar
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png'}, 'Selecciona imagen');
if isequal(file,0)
    return;
end

app.img = imread(fullfile(path,file));
if size(app.img,3) == 3
    app.img = rgb2gray(app.img);
end

imshow(app.img, 'Parent', app.UIAxesImagen);
axis(app.UIAxesImagen, 'off');
title(app.UIAxesImagen, 'Imagen ecográfica');
        end

        % Button pushed function: DibujarlesionButton
        function DibujarlesionButtonPushed(app, event)
            imshow(app.img, 'Parent', app.UIAxesImagen);
            title(app.UIAxesImagen, 'Dibuja el contorno de la lesión');
            
            h = drawfreehand(app.UIAxesImagen, 'Color','r','LineWidth',1.5);
            app.mask = createMask(h);
            
            imshow(app.mask, 'Parent', app.UIAxesMascara);
            axis(app.UIAxesMascara, 'off');
            title(app.UIAxesMascara, 'Región segmentada');

            if isempty(app.mask)
            uialert(app.UIFigure,'Debe dibujar la lesión primero','Error');
            return;
end
        end

        % Button pushed function: DiagnosticarButton
        function DiagnosticarButtonPushed(app, event)
            % Cargar modelo una sola vez
if isempty(app.modelo)
    load('modeloYparametros.mat', 'bestKNNModel', 'mu', 'sigma');
    app.modelo = bestKNNModel;
    app.mu = mu;
    app.sigma = sigma;
end

% Bounding box real
props = regionprops(app.mask, 'BoundingBox');
bbox = props(1).BoundingBox;

app.subimg = imcrop(app.img, bbox);
app.mask_crop = imcrop(app.mask, bbox);

% Preprocesamiento
I_gray = mat2gray(app.subimg);
I_filt = medfilt2(I_gray, [3 3]);

% Extraer TODAS las características
c_raw = ExtraerCaracteristicas(I_filt, app.mask_crop);

% Cabeceras completas (MISMO orden que ExtraerCaracteristicas)
cabeceras_totales = {'intensidad_media','mediana','desviacion_std','varianza','asimetria','curtosis', ...
    'p10','p90','rango', ...
    'homogeneidad','energia','contraste','correlacion', ...
    'entropia', ...
    'lbp_entropia','lbp_energia', ...
    'area','circularidad','compacidad', ...
    'solidez','excentricidad','extension'};

% Crear tabla completa
T_full = array2table(c_raw, 'VariableNames', cabeceras_totales);

% Variables usadas en el entrenamiento
vars_modelo = {'contraste','area','asimetria','curtosis','correlacion', ...
    'homogeneidad','solidez','excentricidad','compacidad', ...
    'circularidad','energia','lbp_energia','lbp_entropia', ...
    'intensidad_media','desviacion_std'};

% Seleccionar SOLO las del modelo
T_selected = T_full(:, vars_modelo);

% Convertir a vector
X_vector = table2array(T_selected);

% Normalizar correctamente
X_norm = (X_vector - app.mu) ./ app.sigma;

% Predicción KNN
pred = predict(app.modelo, X_norm);

% Mostrar resultado
app.LabelResultado.WordWrap = 'on';
app.LabelResultado.HorizontalAlignment = 'center';
switch pred
    case 1
        txt = 'BAJO RIESGO (Lesión benigna)';
    case 2
        txt = 'RIESGO INTERMEDIO (Sospechosa)';
    case 3
        txt = 'ALTO RIESGO (Malignidad)';
end
app.LabelResultado.Text = ...
    ['Diagnóstico sugerido:', txt];

        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            % Limpiar variables internas
app.img = [];
app.mask = [];
app.subimg = [];
app.mask_crop = [];

% Limpiar ejes
cla(app.UIAxesImagen);
cla(app.UIAxesMascara);

% Limpiar textos
app.LabelResultado.Text = 'Resultado del diagnóstico';

% Mensaje opcional
disp('Sistema listo para una nueva imagen');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesImagen = uiaxes(app.UIFigure);
            app.UIAxesImagen.XTick = [];
            app.UIAxesImagen.YTick = [];
            app.UIAxesImagen.ZTick = [];
            app.UIAxesMascara = uiaxes(app.UIFigure);
            app.UIAxesMascara.XTick = [];
            app.UIAxesMascara.YTick = [];
            app.UIAxesMascara.ZTick = [];
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.DibujarlesionButton = uibutton(app.UIFigure, 'push');
            app.DibujarlesionButton.ButtonPushedFcn = createCallbackFcn(app, @DibujarlesionButtonPushed, true);
            app.DibujarlesionButton.Text = 'Dibujar lesion';
            app.DiagnosticarButton = uibutton(app.UIFigure, 'push');
            app.DiagnosticarButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticarButtonPushed, true);
            app.DiagnosticarButton.Text = 'Diagnosticar';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.PrototipoComputacionalparaPredecirPatologasOvricasLabel = uilabel(app.UIFigure);
            app.PrototipoComputacionalparaPredecirPatologasOvricasLabel.Text = 'Prototipo Computacional para Predecir Patologías Ováricas';
            app.LabelResultado = uilabel(app.UIFigure);
            app.LabelResultado.Text = 'Resultado del diagnóstico';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end